classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeechRecognitionBetaUIFigure  matlab.ui.Figure
        ContentTextAreaLabel           matlab.ui.control.Label
        ContentTextArea                matlab.ui.control.TextArea
        ONOFFLamp                      matlab.ui.control.Lamp
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function txt = HaoAudioTxt(m4AFilename)
global url_use
if isempty(url_use)==1
    api_id = 'Nmz5EO4FYF3qcauYNi7fyLXx'; % Replace the api key
    secret_key = 'Cxv6Z1W7PwU8i5HjdTNgHMfCGY7jlagz'; % Replace the secret key
    url_0 = ['https://openapi.baidu.com/oauth/2.0/token?grant_type=client_credentials&client_id=',api_id,'&client_secret=',secret_key];
    url_cont = webread(url_0);
    url_use = url_cont.access_token;
end

url= 'http://vop.baidu.com/server_api';

[y,Fs] = audioread(m4AFilename);
[P,Q] = rat(16000/Fs);
y = resample(y,P,Q);
Fs = 16000;
wavFilename = 'WavFile.wav';
audiowrite(wavFilename,y,Fs);

[base64string,base64string_len] = base64file('WavFile.wav');
options = weboptions('RequestMethod', 'post','HeaderFields',{ 'Content-Type','application/json'});
options.Timeout =20;
m = struct;
m.format = 'wav';
m.lan = 'zh';
m.token = url_use;
m.len = base64string_len;
m.rate = 16000;
m.speech = base64string;
m.cuid = 'test';
m.channel = 1;
Content = webwrite(url,m,options);
if isfield(Content,'result')
    txt = Content.result{:};
else
    txt = '';
end
end

function [base64string,base64string_len] = base64file(file)
fid = fopen(file,'rb');
bytes = fread(fid);
fclose(fid);
base64string_len = size(bytes,1);
encoder = org.apache.commons.codec.binary.Base64;
base64string = char(encoder.encode(bytes))';
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
t=3;
n=100;
global k;
k=1;
app.ONOFFLamp.Color = [0,1,0];
H = audiorecorder(16000,16,2);      
H.record;
pause(t); 
Hm=H.getaudiodata('double');
H1=Hm(:,1);
audiowrite('ls.wav',Hm,16000);
 while n>0 && k==1
H.resume;
pause(t); 
H.pause;
audiowrite('ls.wav',H1,16000);
Hm=H.getaudiodata('double');
H1=Hm(:,1);
txts=HaoAudioTxt('ls.wav');
app.ContentTextArea.Value = {txts};
   n=n-1;
 end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global k;
            k=0;
            app.ONOFFLamp.Color = [0.65,0.65,0.65];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpeechRecognitionBetaUIFigure = uifigure('Visible', 'off');
            app.SpeechRecognitionBetaUIFigure.Name = 'SpeechRecognitionBeta';
            app.SpeechRecognitionBetaUIFigure.Tag = 'By:QiuYeDx';
            app.ContentTextAreaLabel = uilabel(app.SpeechRecognitionBetaUIFigure);
            app.ContentTextAreaLabel.Text = 'Content';
            app.ContentTextArea = uitextarea(app.SpeechRecognitionBetaUIFigure);
            app.ContentTextArea.Value = {'（在此处显示语音识别结果）'};
            app.ONOFFLamp = uilamp(app.SpeechRecognitionBetaUIFigure);
            app.StartButton = uibutton(app.SpeechRecognitionBetaUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SpeechRecognitionBetaUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.SpeechRecognitionBetaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeechRecognitionBetaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeechRecognitionBetaUIFigure)
        end
    end
end