classdef fztest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SRbetaUIFigure                  matlab.ui.Figure
        RecordButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RecognitionRezultTextAreaLabel  matlab.ui.control.Label
        RecognitionRezultTextArea       matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        TimeLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        SpectrogtamButton               matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        RateSliderLabel                 matlab.ui.control.Label
        RateSlider                      matlab.ui.control.Slider
        ResetButton                     matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global rate;
            rate = 16000;
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
    global H;
    global times;
    global k;
    global txttimes;
    app.Lamp.Color = [0 1 0];
    H=audiorecorder(16000,16,2);
    H.record;
    times=0;
    k=1;
    while k==1
        pause(1);
        times=times+1;
        txttimes=int2str(times);
        app.Label.Text={txttimes};
    end
    
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Lamp.Color = [1 1 1];
            global H;
            global ym;
            global txts;
            global k;
            H.pause;
            k=0;
            ym=H.getaudiodata('double');
            y1=ym(:,1);
            audiowrite('rec.wav',y1,16000);
            txts=HaoAudioTxt('rec.wav');
            app.RecognitionRezultTextArea.Value={txts};
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global ym;
            global rate;
            sound(ym,rate);
        end

        % Button pushed function: SpectrogtamButton
        function SpectrogtamButtonPushed(app, event)
            global ym;
            y1=ym(:,1);
            figure(1);
            plot(y1);
            title('原始语音信号');
            grid on;
            figure(2);
            spectrogram(y1,256,128,256,16000,'yaxis');
            xlabel('时间(s)');
            ylabel('频率(Hz)');
            title('语谱图');
        end

        % Value changed function: RateSlider
        function RateSliderValueChanged(app, event)
            value = app.RateSlider.Value;
            global rate;
            rate = value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global rate;
            rate = 16000;
            app.RateSlider.Value = 16000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SRbetaUIFigure = uifigure('Visible', 'off');
            app.SRbetaUIFigure.Name = 'SR beta';
            app.RecordButton = uibutton(app.SRbetaUIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.SRbetaUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RecognitionRezultTextAreaLabel = uilabel(app.SRbetaUIFigure);
            app.RecognitionRezultTextAreaLabel.Text = 'Recognition Rezult';
            app.RecognitionRezultTextArea = uitextarea(app.SRbetaUIFigure);
            app.RecognitionRezultTextArea.Value = {'(识别内容）'};
            app.Lamp = uilamp(app.SRbetaUIFigure);
            app.TimeLabel = uilabel(app.SRbetaUIFigure);
            app.TimeLabel.Text = 'Time:';
            app.Label = uilabel(app.SRbetaUIFigure);
            app.Label.Text = {'0'; ''};
            app.sLabel = uilabel(app.SRbetaUIFigure);
            app.sLabel.Text = 's';
            app.SpectrogtamButton = uibutton(app.SRbetaUIFigure, 'push');
            app.SpectrogtamButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogtamButtonPushed, true);
            app.SpectrogtamButton.Text = 'Spectrogtam';
            app.PlayButton = uibutton(app.SRbetaUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RateSliderLabel = uilabel(app.SRbetaUIFigure);
            app.RateSliderLabel.Text = 'Rate';
            app.RateSlider = uislider(app.SRbetaUIFigure);
            app.RateSlider.Limits = [8000 44100];
            app.RateSlider.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateSlider.Value = 16000;
            app.ResetButton = uibutton(app.SRbetaUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.SRbetaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fztest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SRbetaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SRbetaUIFigure)
        end
    end
end